classdef BrainTumorClassification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResultsPanel           matlab.ui.container.Panel
        PredictionLabel        matlab.ui.control.Label
        TrueLabel              matlab.ui.control.Label
        SettingsPanel          matlab.ui.container.Panel
        OptimizerDropDown      matlab.ui.control.DropDown
        OptimizerLabel         matlab.ui.control.Label
        ModelDropDown          matlab.ui.control.DropDown
        ModelLabel             matlab.ui.control.Label
        ImageLabel             matlab.ui.control.Label
        ClassifyButton         matlab.ui.control.Button
        LoadCustomImageButton  matlab.ui.control.Button
        ImageAxes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        SelectedModel      % Currently selected network
        SelectedInputSize  % Its input size [h w]
        LastImageFile      % Last loaded image path
        AppFolder          % Folder containing this .mlapp
    end

    methods (Access = private)

        function updateImage(app, imagefile)
            % Clear axes and placeholder
            cla(app.ImageAxes);
            app.ImageAxes.Title.String = '';
            % Read image
            try
                im = imread(imagefile);
            catch ME
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            % Display
            imagesc(app.ImageAxes, im);
            axis(app.ImageAxes, 'image');
            app.ImageAxes.Visible = 'on';

            % Update last path and true label
            app.LastImageFile = imagefile;
            folderPath = fileparts(imagefile);
            [~, trueLabel] = fileparts(folderPath);
            app.TrueLabel.Text = ['True Label: ' trueLabel];

            % Clear previous prediction
            app.PredictionLabel.Text = 'Prediction:';
        end

        function updateSelectedModel(app)
            % Get selections
            grp = string(app.ModelDropDown.Value);
            opt = string(app.OptimizerDropDown.Value);
            % Determine .mat filename
            switch grp
                case 'High-CNN'
                    matFile = 'High_trainedCustomCNN_BrainTumor.mat';
                case 'Low-CNN'
                    matFile = 'Low_trainedCustomCNN_BrainTumor.mat';
                case 'High-Squeeze'
                    matFile = 'High_trainedSqueezeNet_BrainTumor.mat';
                case 'Low-Squeeze'
                    matFile = 'Low_trainedSqueezeNet_BrainTumor.mat';
                case 'High-Google'
                    matFile = 'Trained_High_GoogLeNet_BrainTumor.mat';
                case 'Low-Google'
                    matFile = 'Trained_Low_GoogLeNet_BrainTumor.mat';
                case 'High-Mobile-v2'
                    matFile = 'Trained_High_MobileNetV2_BrainTumor.mat';
                case 'Low-Mobile-v2'
                    matFile = 'Trained_Low_MobileNetV2_BrainTumor.mat';
                otherwise
                    error('Unknown model: %s', grp);
            end
            % Load from app folder
            matPath = fullfile(app.AppFolder, matFile);
            tmp = load(matPath);
            % Select optimizer network
            fns = fieldnames(tmp);
            idx = find( contains(lower(fns), lower(opt)), 1 );
            if isempty(idx)
                error('Optimizer not found: %s', opt);
            end
            net = tmp.(fns{idx});
            % Save selected model
            app.SelectedModel = net;

            sz = net.Layers(1).InputSize;
            %app.SelectedInputSize = sz(1:2);

            app.SelectedInputSize = sz;  % tüm üç boyutu sakla
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Determine app folder
            app.AppFolder = fileparts(which(mfilename));
            % Setup placeholder axes
            app.ImageAxes.Visible = 'off';
            axis(app.ImageAxes, 'off');
            text(app.ImageAxes, 0.5, 0.5, 'Load an image to begin', ...
                'Units','normalized', 'HorizontalAlignment','center', ...
                'FontSize',14, 'Color',[.6 .6 .6]);
            % Initialize dropdown data
            app.ModelDropDown.ItemsData = {'High-CNN', 'Low-CNN', ...
                'High-Squeeze', 'Low-Squeeze', 'High-Google', 'Low-Google', ...
                'High-Mobile-v2', 'Low-Mobile-v2'};
            app.OptimizerDropDown.ItemsData = {'adam','rms','sgd'};
            app.ModelDropDown.Value = app.ModelDropDown.ItemsData{1};
            app.OptimizerDropDown.Value = app.OptimizerDropDown.ItemsData{2};
            % Load initial selection
            updateSelectedModel(app);
        end

        % Button pushed function: LoadCustomImageButton
        function LoadCustomImageButtonPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            startFolder = fullfile(app.AppFolder,'brain_tumor_dataset');
            [file,path] = uigetfile(filterspec,'Select an Image', startFolder);
            if isequal(path,0), return; end
            fname = fullfile(path,file);
            updateImage(app,fname);
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            % Clear label
            app.PredictionLabel.Text = 'Prediction:';
            if isempty(app.LastImageFile)
                uialert(app.UIFigure,'First upload an image.','No Image');
                return;
            end
            % Create datastore + augment
            imds = imageDatastore(app.LastImageFile,'LabelSource','none');
            augDs = augmentedImageDatastore(app.SelectedInputSize, imds, ...
                'OutputSizeMode','resize');


            % % Get raw scores
            % scores = predict(app.SelectedModel, augDs);
            % % If not probabilities, apply stable softmax
            % if any(scores<0) || any(scores>1)
            %     shifted = scores - max(scores,[],2);
            %     expV = exp(shifted);
            %     probs = expV ./ sum(expV,2);
            % else
            %     probs = scores;
            % end

            % 3) Ağdan “scores” al (bazen logit, bazen posterior)
            scores = predict(app.SelectedModel, augDs);  % 1×C

            % 4) Eğer bu bir olasılık vektörü değilse softmax uygula
            tol = 1e-3;
            if abs(sum(scores,2) - 1) > tol
                % toolbox stable softmax
                probs = softmax(scores, 2);
            else
                % zaten olasılık
                probs = scores;
            end

            % Pick top
            [pmax,idx] = max(probs, [], 2);
            classes = app.SelectedModel.Layers(end).Classes;
            lbl = classes(idx);
            % Display
            txt = sprintf(['Prediction:\n', ...
                '%s + %s → %s (%.2f%%)'], ...
                upper(strrep(string(app.ModelDropDown.Value),'_','-')), ...
                upper(string(app.OptimizerDropDown.Value)), ...
                string(lbl), pmax*100);
            app.PredictionLabel.Text = txt;
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            updateSelectedModel(app);
        end

        % Value changed function: OptimizerDropDown
        function OptimizerDropDownValueChanged(app, event)
            updateSelectedModel(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadCustomImageButton = uibutton(app.UIFigure, 'push');
            app.LoadCustomImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomImageButtonPushed, true);
            app.LoadCustomImageButton.Text = 'Load Custom Image';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.ModelLabel = uilabel(app.SettingsPanel);
            app.ModelLabel.Text = 'Model:';
            app.ModelDropDown = uidropdown(app.SettingsPanel);
            app.ModelDropDown.Items = {'High-CNN', 'Low-CNN', 'High-Squeeze', 'Low-Squeeze', 'High-Google', 'Low-Google', 'High-Mobile-v2', 'Low-Mobile-v2'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Placeholder = 'High‑CNN, Low‑CNN, High‑Squeeze, Low‑Squeeze, High-Google, Low-Google, High-Mobile-V2, Low-Mobile-V2';
            app.ModelDropDown.Value = 'High-CNN';
            app.OptimizerLabel = uilabel(app.SettingsPanel);
            app.OptimizerLabel.Text = 'Optimizer:';
            app.OptimizerDropDown = uidropdown(app.SettingsPanel);
            app.OptimizerDropDown.Items = {'Adam', 'RMSProp', 'SGD'};
            app.OptimizerDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizerDropDownValueChanged, true);
            app.OptimizerDropDown.Placeholder = 'Adam, RMSProp, SGD';
            app.OptimizerDropDown.Value = 'RMSProp';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.TrueLabel = uilabel(app.ResultsPanel);
            app.TrueLabel.Text = 'True Label:';
            app.PredictionLabel = uilabel(app.ResultsPanel);
            app.PredictionLabel.Text = 'Prediction:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainTumorClassification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end